classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UploadImage2Button  matlab.ui.control.Button
        UploadImage1Button  matlab.ui.control.Button
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
        StartFusionButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        P=[] % Description
        Q=[] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartFusionButton
        function StartFusionButtonPushed(app, event)
            P = app.P;
            Q = app.Q;
            if size(Q,3)==3       % For MRI-SPECT Image Fusion
                tic
                P=double(P)/255;
                Q=double(Q)/255;
                Q_YUV=ConvertRGBtoYUV(Q);
                Q_Y=Q_YUV(:,:,1);
                [hei, wid]=size(P);
                F_Y=Medical_Fusion(P,Q_Y);  % Performing Image Fusion
                F_YUV=zeros(hei,wid,3);
                F_YUV(:,:,1)=F_Y;
                F_YUV(:,:,2)=Q_YUV(:,:,2);
                F_YUV(:,:,3)=Q_YUV(:,:,3);
                F=ConvertYUVtoRGB(F_YUV);
                F=uint8(F*255);
                toc
                figure, imshow(F)          
          else                       % For Medical Image Fusion where both the source images are gray-scale                       
                tic
                P=double(P)/255;
                Q=double(Q)/255;
                F=Medical_Fusion(P,Q);   % Performing Image Fusion
                F=uint8(F*255);
                toc
                figure, imshow(F) 
          end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [baseFileName, folder] = uigetfile({'*'},'Select first image file');
            fullFileName = fullfile(folder, baseFileName);
            app.P = imread(baseFileName);
            app.Image.ImageSource = fullfile(folder, baseFileName);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            [baseFileName, folder] = uigetfile({'*'},'Select second image file');
            fullFileName = fullfile(folder, baseFileName);
            app.Q = imread(baseFileName);
            app.Image2.ImageSource = fullfile(folder, baseFileName);
        end

        % Button pushed function: UploadImage1Button
        function UploadImage1ButtonPushed(app, event)
            [baseFileName, folder] = uigetfile({'*'},'Select first image file');
            fullFileName = fullfile(folder, baseFileName);
            app.P = imread(baseFileName);
            app.Image.ImageSource = fullfile(folder, baseFileName);
        end

        % Button pushed function: UploadImage2Button
        function UploadImage2ButtonPushed(app, event)
            [baseFileName, folder] = uigetfile({'*'},'Select second image file');
            fullFileName = fullfile(folder, baseFileName);
            app.Q = imread(baseFileName);
            app.Image2.ImageSource = fullfile(folder, baseFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartFusionButton = uibutton(app.UIFigure, 'push');
            app.StartFusionButton.ButtonPushedFcn = createCallbackFcn(app, @StartFusionButtonPushed, true);
            app.StartFusionButton.Text = 'Start Fusion';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.UploadImage1Button = uibutton(app.UIFigure, 'push');
            app.UploadImage1Button.ButtonPushedFcn = createCallbackFcn(app, @UploadImage1ButtonPushed, true);
            app.UploadImage1Button.Text = 'Upload Image 1';
            app.UploadImage2Button = uibutton(app.UIFigure, 'push');
            app.UploadImage2Button.ButtonPushedFcn = createCallbackFcn(app, @UploadImage2ButtonPushed, true);
            app.UploadImage2Button.Text = 'Upload Image 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end